classdef augmentedapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        EEE312ProjectGroup1Label  matlab.ui.control.Label
        PressstarttoinitiateCommands1tomoveforward2Label  matlab.ui.control.Label
        DemonstrationInterfaceforVoiceControlledWheelChairLabel  matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        CLOSEButton               matlab.ui.control.Button
        STARTButton               matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            % physical settings
            clc;
            scale = 4;
            v = [0 1]/scale;
            gap = 0.25;
            pos = [0 1];
            time = 0.05;
            T = 10;  % time req to perform a full 360 rotation
            w = 2*pi/T;
            theta = time*w;
            thetad = 360/T*time;
            sign = 1;
            CCW = [cos(theta) -sin(theta);sin(theta) cos(theta)];
            CW = [cos(-theta) -sin(-theta);sin(-theta) cos(-theta)];
            angle = 0;
            v = ([cosd(angle) -sind(angle);sind(angle) cosd(angle)]*v')';

            %load saved model
            load('trained_net_male_dataset_25_July.mat');
            
            %audio settings
            fs = 44.1e3;
            delay = 100 ;    % in miliseconds, new scan duration
            window_time = 2;    %displayed data length in seconds
            deviceReader = audioDeviceReader(fs,delay/1000*fs);
            audio = zeros(1,window_time*fs);
            commandnum = 6;
            activeCommand = 'background';
            memory = zeros(1,ceil(0.75*window_time*1000/delay));      % storing previous outcomes to neglect false detections
            tol = 0.5;  % percentage of the memory to be a particular command to be sure
            testingLimit = 0.005*window_time*fs;  % power limit for idle state
            count = 1;
            
          
            while 1
                tic

                %get keyword
                audiotemp = deviceReader();
                audio = circshift(audio,-delay/1000*fs);
                audio = [audio(1:window_time*fs - delay/1000*fs) audiotemp'];


                if sum(abs(audio)) > testingLimit
                    %         disp('skipped');
                    %continue;
                    auditorySpect = MyhelperExtractAuditoryFeatures(audio',fs);
                    size(auditorySpect);
                    auditorySpect_cropped = auditorySpect(:,:);
                    temp_command = classify(trainedNet,auditorySpect_cropped);
                    %disp(i);
                else
                    temp_command = 6;
                end
                
                
                %extra layer for false detection
                % 4 for shamne, 3 for pichone, 2 dane, 1 bame, 5 thamo 6 bg
                memory = circshift(memory,-1);
                memory(end) = temp_command;
                
                if length(find(memory == memory(end))) > tol*length(memory)
                    command = temp_command;
                    if memory(end) ~= 6
                        if  ~strcmp(char(command),char(activeCommand))
                            activeCommand = command;
                            count = 1;
                            commandnum = memory(end);
                        end
                    end

                end
                %disp(commandnum);
                
                % rotation lock to 90 degrees 
                if count > T/time*90/360 && (commandnum == 2 | commandnum == 1)
                    commandnum = 5;
                    activeCommand = 'thamo';
                end
                
                
                switch commandnum
                    case 4
                        %front
                        sign = 1;
                    case 3
                        %back
                        sign = -1;
                    case 2
                        %right
                         v = (CW*v')';
                         angle = angle - thetad;
                         sign = 1;
                    case 1
                        %left
                        v = (CCW*v')';
                        angle = angle + thetad;
                        sign = 1;
                    case 5
                        %stop
                        sign = 0;
                    otherwise
                        sign = 0;
                end
                
                %disp(count)
                %disp(angle)
                %disp(activeCommand)

                angle = mod(angle,360);
                serial = round(angle/10);
                app.Image.ImageSource="imgsource/"+ num2str(serial,'%02.f') + ".jpg";


                pos = pos + sign*v*time;
                app.UIAxes.XLim = [-2 2]+pos(1);
                app.UIAxes.YLim = [-1 1]+pos(2);

                t= toc;
                
                %pause(time - t);
                drawnow();
                count = count + 1;
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end

        % Button pushed function: CLOSEButton
        function CLOSEButtonPushed(app, event)
            %release(deviceReader);
            app.delete

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-2 2];
            app.UIAxes.YLim = [-1 1];
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YAxisLocation = 'origin';
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.CLOSEButton = uibutton(app.UIFigure, 'push');
            app.CLOSEButton.ButtonPushedFcn = createCallbackFcn(app, @CLOSEButtonPushed, true);
            app.CLOSEButton.Text = 'CLOSE';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'image.jpg';
            app.DemonstrationInterfaceforVoiceControlledWheelChairLabel = uilabel(app.UIFigure);
            app.DemonstrationInterfaceforVoiceControlledWheelChairLabel.Text = {'Demonstration Interface for Bangla Voice Controlled Wheel Chair  '; ''};
            app.PressstarttoinitiateCommands1tomoveforward2Label = uilabel(app.UIFigure);
            app.PressstarttoinitiateCommands1tomoveforward2Label.Text = {''; 'Press start to initiate'; 'Commands:'; '1. সামনে - to move forward'; '2. পিছনে - to move backward'; '3. বামে - to rotate left(locked at 90 degrees per command)'; '4. ডানে - to rotate right'; '5. থামো - to stop moving'};
            app.EEE312ProjectGroup1Label = uilabel(app.UIFigure);
            app.EEE312ProjectGroup1Label.Text = {'EEE 312 Project'; 'Group 1'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = augmentedapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end